classdef mlp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MLPUIFigure                     matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SelectFilesPanel                matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        AddTrainDataButton              matlab.ui.control.Button
        AddCVDataButton                 matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        FromAnExistingConfigurationButton  matlab.ui.control.Button
        AddTestDataButton               matlab.ui.control.Button
        EditField_7                     matlab.ui.control.EditField
        SettingsPanel                   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        InputSizeEditFieldLabel         matlab.ui.control.Label
        InputSizeEditField              matlab.ui.control.NumericEditField
        BiasEditFieldLabel              matlab.ui.control.Label
        BiasEditField                   matlab.ui.control.NumericEditField
        LearningFactorEditFieldLabel    matlab.ui.control.Label
        LearningFactorEditField         matlab.ui.control.NumericEditField
        ActivationFunctionDropDownLabel  matlab.ui.control.Label
        ActivationFunctionDropDown      matlab.ui.control.DropDown
        EfficiencyLabel                 matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        HiddenLayersSizeLabel           matlab.ui.control.Label
        HiddenLayersSizeEditField       matlab.ui.control.NumericEditField
        ResetSettingsButton             matlab.ui.control.Button
        HiddenLayerCountEditFieldLabel  matlab.ui.control.Label
        HiddenLayerCountEditField       matlab.ui.control.NumericEditField
        DataSetEditFieldLabel           matlab.ui.control.Label
        DataSetEditField                matlab.ui.control.NumericEditField
        OutputSizeLabel                 matlab.ui.control.Label
        OutputSizeEditField             matlab.ui.control.NumericEditField
        EpochLabel_2                    matlab.ui.control.Label
        EpochSpinner                    matlab.ui.control.Spinner
        Panel_3                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        TrainingPanel                   matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        EpochStateEditFieldLabel        matlab.ui.control.Label
        EpochStateEditField             matlab.ui.control.NumericEditField
        StartTrainingButton             matlab.ui.control.Button
        IsDoneLabel                     matlab.ui.control.Label
        IsDoneLamp                      matlab.ui.control.Lamp
        HitCountEditField_3Label        matlab.ui.control.Label
        HitCountEditField_3             matlab.ui.control.NumericEditField
        MissCountEditField_3Label       matlab.ui.control.Label
        MissCountEditField_3            matlab.ui.control.NumericEditField
        CrossValidationPanel            matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        StartCVButton                   matlab.ui.control.Button
        PassLabel                       matlab.ui.control.Label
        PassLamp                        matlab.ui.control.Lamp
        HitCountEditField_2Label        matlab.ui.control.Label
        HitCountEditField_2             matlab.ui.control.NumericEditField
        MissCountEditField_2Label       matlab.ui.control.Label
        MissCountEditField_2            matlab.ui.control.NumericEditField
        EfficiencyStateLabel            matlab.ui.control.Label
        EfficiencyStateEditField        matlab.ui.control.NumericEditField
        TestingPanel                    matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        StartTestButton                 matlab.ui.control.Button
        HitCountEditFieldLabel          matlab.ui.control.Label
        HitCountEditField               matlab.ui.control.NumericEditField
        MissCountEditFieldLabel         matlab.ui.control.Label
        MissCountEditField              matlab.ui.control.NumericEditField
        GridLayout9                     matlab.ui.container.GridLayout
        AllSetCheckBox                  matlab.ui.control.CheckBox
        Panel_4                         matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        GridLayout10                    matlab.ui.container.GridLayout
        KeepTrackButton                 matlab.ui.control.Button
        SaveNetworkButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        networkConfig ai.network.NetworkConfig;
        trainNetwork ai.network.TrainNetwork;
        finalNetwork ai.network.FinalNetwork;
        trainingCompleted logical;
    end
    
    properties (Constant)
        TRAM_SIZE = 26;
        FIELD_COUNT = 9;
    end
    
    methods (Access = public)
        
        function SetTrainConfig(app)
            app.networkConfig = ai.network.NetworkConfig;
            app.networkConfig.trainNetwork = ai.network.TrainNetwork;
            app.networkConfig.finalNetwork = ai.network.FinalNetwork(app.networkConfig.trainNetwork);
            
            app.networkConfig.trainDataFile = app.EditField.Value;
            app.networkConfig.vcDataFile = app.EditField_2.Value;
            app.networkConfig.testDataFile = app.EditField_7.Value;
            app.networkConfig.hiddenLayersSize = app.HiddenLayersSizeEditField.Value;
            app.networkConfig.hiddenLayerCount = app.HiddenLayerCountEditField.Value;
            app.networkConfig.inputSize = app.InputSizeEditField.Value;
            app.networkConfig.outputSize = app.OutputSizeEditField.Value;
            app.networkConfig.bias = app.BiasEditField.Value;
            app.networkConfig.learningFactor = app.LearningFactorEditField.Value;
            app.networkConfig.actFnc = str2func(app.ActivationFunctionDropDown.Value);
            app.networkConfig.dataSet = app.DataSetEditField.Value;
            %             app.networkConfig.efficiency = app.EfficiencyEditField.Value;
            %             app.networkConfig.epoch = app.EpochSpinner.Value;
        end
    end
    methods (Access = private)
        function train(app)
            if (app.StartCVButton.Enable == "on")
                app.StartCVButton.Enable = "off";
            end
            app.IsDoneLamp.Color = '1.00,0.00,0.00';
            drawnow;
            
            epochCount = app.EpochSpinner.Value - app.EpochStateEditField.Value;
            if (epochCount == app.EpochSpinner.Value)
                d = uiprogressdlg(app.MLPUIFigure,'Title','Preparing Data',...
                    'Indeterminate','on');
                
                app.EpochStateEditField.Value = 0;
                app.trainNetwork = ai.network.TrainNetwork;
                app.SetTrainConfig;
                app.networkConfig.trainNetwork.setTrainingConfig(app.networkConfig);
                
                close(d);
            end
            
            d = uiprogressdlg(app.MLPUIFigure,'Title', 'Training Stage', ...
                'ShowPercentage', 'on', 'Message', 'Working...');
            
            iterationSize = size(app.networkConfig.trainNetwork.trainData);
            iter = cast(iterationSize(1)*epochCount, 'double');
            iterCount = 0;
            
            % epoch loop
            for i = 1 : epochCount
                % set the temp training data on each epoch
                app.networkConfig.trainNetwork.tmpTrainData = app.networkConfig.trainNetwork.trainData;
                % iteration loop
                for j = 1 : iterationSize(1)
                    iterCount = iterCount + 1;
                    v = iterCount/iter;
                    d.Value = v;
                    
                    % set input and output values
                    % input values
                    tram = app.networkConfig.trainNetwork.getTram('train');
                    app.networkConfig.trainNetwork.inputLayer.setLayerOutputValues(tram);
                    % output values
                    outputValues = zeros(1, app.OutputSizeEditField.Value);
                    outputValues(app.networkConfig.trainNetwork.tramDigit + 1) = 1;
                    app.networkConfig.trainNetwork.outputLayer.setLayerOutputValues(outputValues);
                    
                    % walk layers forward
                    l = app.networkConfig.trainNetwork.hiddenLayers.firstElem;
                    while(~isempty(l))
                        l.data.runLayerForward;
                        
                        % if the last layer (output layer)
                        if (isempty(l.next))
                            digit = l.data.getOutputDigit;
                            
%                             disp('------------------------');
%                             disp('input digit:');
%                             disp(app.networkConfig.trainNetwork.tramDigit)
%                             disp('output digit:');
%                             disp(digit);
%                             disp('------------------------');
                            
                            if (digit == app.networkConfig.trainNetwork.tramDigit)
                                app.HitCountEditField_3.Value = app.HitCountEditField_3.Value + 1;
                            else
                                app.MissCountEditField_3.Value = app.MissCountEditField_3.Value + 1;
                            end
                            %                             xl = linspace(app.EpochStateEditField.Value,y);
                            %                             plot(app.UIAxes,xl ,y);
                            drawnow;
                        end
                        
                        l = l.next;
                    end
                    
                    % walk layers backward
                    l = app.networkConfig.trainNetwork.hiddenLayers.lastElem;
                    while(~isempty(l))
                        l.data.runLayerBackward;
                        l = l.prev;
                    end
                end
                app.EpochStateEditField.Value = app.EpochStateEditField.Value + 1;
                drawnow;
            end
            close(d);
            if (app.EpochStateEditField.Value == app.EpochSpinner.Value)
                app.IsDoneLamp.Color = '0.00,1.00,0.00';
                drawnow;
                app.StartCVButton.Enable = true;
            end
        end
        
        function crossValidation(app)
            app.PassLamp.Color = '1.00,0.00,0.00';
            app.HitCountEditField_2.Value = 0;
            app.MissCountEditField_2.Value = 0;
            drawnow;
            
            
            d = uiprogressdlg(app.MLPUIFigure,'Title', 'Cross Validation Stage', ...
                'ShowPercentage', 'on', 'Message', 'Working...');
            
            app.networkConfig.trainNetwork.tmpVcData = app.networkConfig.trainNetwork.vcData;
            
            iterationSize = size(app.networkConfig.trainNetwork.vcData);
            
            for i = 1 : iterationSize(1)
                v = i/iterationSize(1);
                d.Value = v;
                
                % set input and get output values
                % set input values
                tram = app.networkConfig.trainNetwork.getTram('cv');
                app.networkConfig.trainNetwork.inputLayer.setLayerOutputValues(tram);
                
                % walk layers forward
                l = app.networkConfig.trainNetwork.hiddenLayers.firstElem;
                while(~isempty(l))
                    l.data.runLayerForward;
                    
                    % if the last layer (output layer)
                    if (isempty(l.next))
                        digit = l.data.getOutputDigit;
                        
                        disp('------------------------');
                        disp('input digit:');
                        disp(app.networkConfig.trainNetwork.tramDigit)
                        disp('output digit:');
                        disp(digit);
                        disp('------------------------');
                        
                        
                        if (digit == app.networkConfig.trainNetwork.tramDigit)
                            app.HitCountEditField_2.Value = app.HitCountEditField_2.Value + 1;
                        else
                            app.MissCountEditField_2.Value = app.MissCountEditField_2.Value + 1;
                        end
                        app.EfficiencyStateEditField.Value = (app.HitCountEditField_2.Value / app.MissCountEditField_2.Value) * 100;
                        drawnow;
                    end
                    
                    l = l.next;
                end
            end
            close(d);
            if (app.EfficiencyStateEditField.Value >= app.EfficiencyEditField.Value)
                app.PassLamp.Color = '0.00,1.00,0.00';
                app.StartTestButton.Enable = true;
                drawnow;
            end
        end
        
        function testNetwork(app)
            app.HitCountEditField.Value = 0;
            app.MissCountEditField.Value = 0;
            drawnow;
            
            d = uiprogressdlg(app.MLPUIFigure,'Title', 'Testing Stage', ...
                'ShowPercentage', 'on', 'Message', 'Working...');
            
            app.networkConfig.trainNetwork.tmpTestData = app.networkConfig.trainNetwork.testData;
            
            iterationSize = size(app.networkConfig.trainNetwork.testData);
            for i = 1 : iterationSize(1)
                v = i/iterationSize(1);
                d.Value = v;
                
                % set input and get output values
                % set input values
                tram = app.networkConfig.trainNetwork.getTram('test');
                app.networkConfig.trainNetwork.inputLayer.setLayerOutputValues(tram);
                
                % walk layers forward
                l = app.networkConfig.trainNetwork.hiddenLayers.firstElem;
                while(~isempty(l))
                    l.data.runLayerForward;
                    
                    % if the last layer (output layer)
                    if (isempty(l.next))
                        digit = l.data.getOutputDigit;
                        
                        %                         disp('------------------------');
                        %                         disp('input digit:');
                        %                         disp(app.networkConfig.trainNetwork.tramDigit)
                        %                         disp('output digit:');
                        %                         disp(digit);
                        %                         disp('------------------------');
                        
                        
                        if (digit == app.networkConfig.trainNetwork.tramDigit)
                            app.HitCountEditField.Value = app.HitCountEditField.Value + 1;
                        else
                            app.MissCountEditField.Value = app.MissCountEditField.Value + 1;
                        end
                        drawnow;
                    end
                    l = l.next;
                end
            end
            close(d);
            app.SaveNetworkButton.Enable = true;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % networks and configuration setup
            app.SetTrainConfig;
            
            % graph setting
            hold(app.UIAxes,"on");
   
            % initialize UI components
            app.StartTrainingButton.Enable = false;
            app.StartCVButton.Enable = false;
            app.StartTestButton.Enable = false;
            app.SaveNetworkButton.Enable = false;
            app.KeepTrackButton.Enable = false;
        end

        % Button pushed function: AddTrainDataButton
        function AddTrainDataButtonPushed(app, event)
            [file,path] = uigetfile('./input/*.txt');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.EditField.Enable = true;
                app.EditField.Value = file;
                app.EditField.FontAngle = 'normal';
            end
        end

        % Button pushed function: AddCVDataButton
        function AddCVDataButtonPushed(app, event)
            [file,path] = uigetfile('./input/*.txt');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.EditField_2.Enable = true;
                app.EditField_2.Value = file;
                app.EditField_2.FontAngle = 'normal';
            end
        end

        % Button pushed function: FromAnExistingConfigurationButton
        function FromAnExistingConfigurationButtonPushed(app, event)
            [file,path] = uigetfile('./output/configuration/*.mat');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.networkConfig = matfile(file, 'Writable',true).ts;
                
                app.EditField.Value = app.networkConfig.trainDataFile;
                app.EditField.FontAngle = "normal";
                app.EditField.Enable = true;
                app.EditField_2.Value = app.networkConfig.vcDataFile;
                app.EditField_2.FontAngle = "normal";
                app.EditField_2.Enable = true;
                app.EditField_7.Value = app.networkConfig.testDataFile;
                app.EditField_7.FontAngle = "normal";
                app.EditField_7.Enable = true;
                app.HiddenLayersSizeEditField.Value = cast(app.networkConfig.hiddenLayersSize, 'double');
                app.HiddenLayerCountEditField.Value = cast(app.networkConfig.hiddenLayerCount, 'double');
                app.InputSizeEditField.Value = cast(app.networkConfig.inputSize, 'double');
                app.OutputSizeEditField.Value = cast(app.networkConfig.outputSize, 'double');
                app.BiasEditField.Value = cast(app.networkConfig.bias, 'double');
                app.LearningFactorEditField.Value = app.networkConfig.learningFactor;
                app.ActivationFunctionDropDown.Value = func2str(app.networkConfig.actFnc);
                app.EpochSpinner.Value = cast(app.networkConfig.epoch, 'double');
                app.EfficiencyEditField.Value = app.networkConfig.efficiency;
                %app.TimersecondsEditField.Value = cast(app.trainConfig.timeSeconds, 'double');
                app.DataSetEditField.Value = cast(app.networkConfig.dataSet, 'double');
                app.existingNetwork = app.networkConfig.trainNetwork;
                %                 app.AllSetCheckBox.Value = 1;
                app.StartTestButton.Enable = true;
            end
        end

        % Button pushed function: SaveNetworkButton
        function SaveNetworkButtonPushed(app, event)
            % save network configuration
            app.networkConfig.trainNetwork = app.trainNetwork;
            app.networkConfig.finalNetwork = app.finalNetwork(app.networkConfig.trainNetwork);
            %             app.networkConfig.trainDataFile = app.EditField.Value;
            %             app.networkConfig.vcDataFile = app.EditField_2.Value;
            %             app.networkConfig.testDataFile = app.EditField_7.Value;
            %
            %             app.networkConfig.hiddenLayersSize = app.HiddenLayersSizeEditField.Value;
            %             app.networkConfig.hiddenLayerCount = app.HiddenLayerCountEditField.Value;
            %             app.networkConfig.inputSize = app.InputSizeEditField.Value;
            %             app.networkConfig.outputSize = app.OutputSizeEditField.Value;
            %             app.networkConfig.bias = app.BiasEditField.Value;
            %             app.networkConfig.learningFactor = app.LearningFactorEditField.Value;
            %             app.networkConfig.actFnc = str2func(app.ActivationFunctionDropDown.Value);
            app.networkConfig.epoch = app.EpochSpinner.Value;
            app.networkConfig.efficiency = app.EfficiencyEditField.Value;
            %             app.networkConfig.dataSet = app.DataSetEditField.Value;
            
            fname = "dataSet"+app.networkConfig.dataSet+ ...
                "_hls"+app.networkConfig.hiddenLayersSize+ ...
                "_hlc"+app.networkConfig.hiddenLayerCount+ ...
                "_is"+app.networkConfig.inputSize+ ...
                "_os"+app.networkConfig.outputSize+ ...
                "_b"+app.networkConfig.bias+ ...
                "_lf"+app.networkConfig.learningFactor+ ...
                "_"+app.ActivationFunctionDropDown.Value+ ...
                "_epoch"+app.networkConfig.epoch+ ...
                "_%"+app.networkConfig.efficiency;
            
            ts = app.networkConfig;
            [file,path] = uiputfile("./output/configuration/"+fname+".mat");
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                save([path,file], 'ts');
            end
            
        end

        % Button pushed function: AddTestDataButton
        function AddTestDataButtonPushed(app, event)
            [file,path] = uigetfile('./input/*.txt');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.EditField_7.Value = file;
                app.EditField_7.Enable = true;
                app.EditField_7.FontAngle = 'normal';
            end
        end

        % Button pushed function: ResetSettingsButton
        function ResetSettingsButtonPushed(app, event)
            app.EditField.Value = "File Name";
            app.EditField.FontAngle = "italic";
            app.EditField.Enable = false;
            app.EditField_2.Value = "File Name";
            app.EditField_2.FontAngle = "italic";
            app.EditField_2.Enable = false;
            app.EditField_7.Value = "File Name";
            app.EditField_7.FontAngle = "italic";
            app.EditField_7.Enable = false;
            app.HiddenLayersSizeEditField.Value = 0.0;
            app.HiddenLayerCountEditField.Value = 0.0;
            app.InputSizeEditField.Value = 0.0;
            app.OutputSizeEditField.Value = 0.0;
            app.BiasEditField.Value = 0.0;
            app.LearningFactorEditField.Value = 0.0;
            app.ActivationFunctionDropDown.Value = "Sigmoid";
            app.EpochSpinner.Value = 0.0;
            app.EpochStateEditField.Value = 0.0;
            app.EfficiencyEditField.Value = 0.0;
            app.EfficiencyStateEditField.Value = 0.0;
            app.DataSetEditField.Value = 0.0;
            app.HitCountEditField.Value = 0.0;
            app.MissCountEditField.Value = 0.0;
            app.HitCountEditField_2.Value = 0.0;
            app.MissCountEditField_2.Value = 0.0;
            app.HitCountEditField_3.Value = 0.0;
            app.MissCountEditField_3.Value = 0.0;
            app.IsDoneLamp.Color = '0.80,0.80,0.80';
            app.PassLamp.Color = '0.80,0.80,0.80';
            app.StartCVButton.Enable = false;
            app.StartCVButton.Enable = false;
            app.StartTestButton.Enable = false;
            app.AllSetCheckBox.Value = 0;
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            app.train;
        end

        % Value changed function: DataSetEditField
        function DataSetEditFieldValueChanged(app, event)
            size = app.DataSetEditField.Value * app.TRAM_SIZE;
            app.InputSizeEditField.Value = size;
            app.networkConfig.inputSize = size;
        end

        % Value changed function: AllSetCheckBox
        function AllSetCheckBoxValueChanged(app, event)
            value = app.AllSetCheckBox.Value;
            if (value == 1)
                app.StartTrainingButton.Enable = true;
            else
                app.StartTrainingButton.Enable = false;
            end
        end

        % Value changed function: HiddenLayerCountEditField
        function HiddenLayerCountEditFieldValueChanged(app, event)
            value = app.HiddenLayerCountEditField.Value;
            
        end

        % Value changed function: HiddenLayersSizeEditField
        function HiddenLayersSizeEditFieldValueChanged(app, event)
            value = app.HiddenLayersSizeEditField.Value;
            
        end

        % Value changed function: OutputSizeEditField
        function OutputSizeEditFieldValueChanged(app, event)
            value = app.OutputSizeEditField.Value;
            
        end

        % Value changed function: BiasEditField
        function BiasEditFieldValueChanged(app, event)
            value = app.BiasEditField.Value;
            
        end

        % Value changed function: LearningFactorEditField
        function LearningFactorEditFieldValueChanged(app, event)
            value = app.LearningFactorEditField.Value;
            
        end

        % Value changed function: EfficiencyEditField
        function EfficiencyEditFieldValueChanged(app, event)
            value = app.EfficiencyEditField.Value;
            yline(app.UIAxes,value, 'r', 'Efficiency (%)');
        end

        % Value changed function: EpochSpinner
        function EpochSpinnerValueChanged(app, event)
            value = app.EpochSpinner.Value;
            
        end

        % Button pushed function: StartCVButton
        function StartCVButtonPushed(app, event)
            app.crossValidation;
            
            %             x = [1 2 3 4 5 6 7 8 9 10 11];
            %             y = [75 91 105 123.5 131 150 179 203 226 249 281.5];
            %             t = linspace(2, 7);
            %             s = size(t);
            %             hold(app.UIAxes);
            %             plot(app.UIAxes,t, y);
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            app.testNetwork;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MLPUIFigure = uifigure('Visible', 'off');
            app.MLPUIFigure.Name = 'MLP';
            app.Panel = uipanel(app.MLPUIFigure);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.SelectFilesPanel = uipanel(app.GridLayout2);
            app.SelectFilesPanel.Title = 'Select Files';
            app.GridLayout = uigridlayout(app.SelectFilesPanel);
            app.AddTrainDataButton = uibutton(app.GridLayout, 'push');
            app.AddTrainDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrainDataButtonPushed, true);
            app.AddTrainDataButton.Text = 'Add Train Data';
            app.AddCVDataButton = uibutton(app.GridLayout, 'push');
            app.AddCVDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddCVDataButtonPushed, true);
            app.AddCVDataButton.Text = 'Add CV Data';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Value = 'File Name';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 'File Name';
            app.FromAnExistingConfigurationButton = uibutton(app.GridLayout, 'push');
            app.FromAnExistingConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @FromAnExistingConfigurationButtonPushed, true);
            app.FromAnExistingConfigurationButton.Text = 'From An Existing Configuration';
            app.AddTestDataButton = uibutton(app.GridLayout, 'push');
            app.AddTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddTestDataButtonPushed, true);
            app.AddTestDataButton.Text = 'Add Test Data';
            app.EditField_7 = uieditfield(app.GridLayout, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Enable = 'off';
            app.EditField_7.Value = 'File Name';
            app.SettingsPanel = uipanel(app.GridLayout2);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout3 = uigridlayout(app.SettingsPanel);
            app.InputSizeEditFieldLabel = uilabel(app.GridLayout3);
            app.InputSizeEditFieldLabel.Text = 'Input Size: ';
            app.InputSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.InputSizeEditField.Editable = 'off';
            app.BiasEditFieldLabel = uilabel(app.GridLayout3);
            app.BiasEditFieldLabel.Text = 'Bias: ';
            app.BiasEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BiasEditField.ValueChangedFcn = createCallbackFcn(app, @BiasEditFieldValueChanged, true);
            app.LearningFactorEditFieldLabel = uilabel(app.GridLayout3);
            app.LearningFactorEditFieldLabel.Text = 'Learning Factor: ';
            app.LearningFactorEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LearningFactorEditField.ValueChangedFcn = createCallbackFcn(app, @LearningFactorEditFieldValueChanged, true);
            app.ActivationFunctionDropDownLabel = uilabel(app.GridLayout3);
            app.ActivationFunctionDropDownLabel.Text = 'Activation Function: ';
            app.ActivationFunctionDropDown = uidropdown(app.GridLayout3);
            app.ActivationFunctionDropDown.Items = {'Sigmoid', 'Tangente'};
            app.ActivationFunctionDropDown.Value = 'Sigmoid';
            app.EfficiencyLabel = uilabel(app.GridLayout3);
            app.EfficiencyLabel.Text = 'Efficiency (%): ';
            app.EfficiencyEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @EfficiencyEditFieldValueChanged, true);
            app.HiddenLayersSizeLabel = uilabel(app.GridLayout3);
            app.HiddenLayersSizeLabel.Text = 'Hidden Layers Size: ';
            app.HiddenLayersSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.HiddenLayersSizeEditField.ValueChangedFcn = createCallbackFcn(app, @HiddenLayersSizeEditFieldValueChanged, true);
            app.ResetSettingsButton = uibutton(app.GridLayout3, 'push');
            app.ResetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSettingsButtonPushed, true);
            app.ResetSettingsButton.Text = 'Reset Settings';
            app.HiddenLayerCountEditFieldLabel = uilabel(app.GridLayout3);
            app.HiddenLayerCountEditFieldLabel.Text = 'Hidden Layer Count: ';
            app.HiddenLayerCountEditField = uieditfield(app.GridLayout3, 'numeric');
            app.HiddenLayerCountEditField.ValueChangedFcn = createCallbackFcn(app, @HiddenLayerCountEditFieldValueChanged, true);
            app.DataSetEditFieldLabel = uilabel(app.GridLayout3);
            app.DataSetEditFieldLabel.Text = 'Data Set: ';
            app.DataSetEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DataSetEditField.ValueChangedFcn = createCallbackFcn(app, @DataSetEditFieldValueChanged, true);
            app.OutputSizeLabel = uilabel(app.GridLayout3);
            app.OutputSizeLabel.Text = 'Output Size: ';
            app.OutputSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.OutputSizeEditField.ValueChangedFcn = createCallbackFcn(app, @OutputSizeEditFieldValueChanged, true);
            app.EpochLabel_2 = uilabel(app.GridLayout3);
            app.EpochLabel_2.Text = 'Epoch: ';
            app.EpochSpinner = uispinner(app.GridLayout3);
            app.EpochSpinner.ValueChangedFcn = createCallbackFcn(app, @EpochSpinnerValueChanged, true);
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.TrainingPanel = uipanel(app.GridLayout5);
            app.TrainingPanel.Title = 'Training';
            app.GridLayout6 = uigridlayout(app.TrainingPanel);
            app.EpochStateEditFieldLabel = uilabel(app.GridLayout6);
            app.EpochStateEditFieldLabel.Text = 'Epoch State: ';
            app.EpochStateEditField = uieditfield(app.GridLayout6, 'numeric');
            app.EpochStateEditField.Editable = 'off';
            app.StartTrainingButton = uibutton(app.GridLayout6, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.IsDoneLabel = uilabel(app.GridLayout6);
            app.IsDoneLabel.Text = 'Is Done ?: ';
            app.IsDoneLamp = uilamp(app.GridLayout6);
            app.HitCountEditField_3Label = uilabel(app.GridLayout6);
            app.HitCountEditField_3Label.Text = 'Hit Count: ';
            app.HitCountEditField_3 = uieditfield(app.GridLayout6, 'numeric');
            app.MissCountEditField_3Label = uilabel(app.GridLayout6);
            app.MissCountEditField_3Label.Text = 'Miss Count';
            app.MissCountEditField_3 = uieditfield(app.GridLayout6, 'numeric');
            app.CrossValidationPanel = uipanel(app.GridLayout5);
            app.CrossValidationPanel.Title = 'Cross-Validation';
            app.GridLayout7 = uigridlayout(app.CrossValidationPanel);
            app.StartCVButton = uibutton(app.GridLayout7, 'push');
            app.StartCVButton.ButtonPushedFcn = createCallbackFcn(app, @StartCVButtonPushed, true);
            app.StartCVButton.Text = 'Start CV';
            app.PassLabel = uilabel(app.GridLayout7);
            app.PassLabel.Text = 'Pass ?: ';
            app.PassLamp = uilamp(app.GridLayout7);
            app.HitCountEditField_2Label = uilabel(app.GridLayout7);
            app.HitCountEditField_2Label.Text = 'Hit Count: ';
            app.HitCountEditField_2 = uieditfield(app.GridLayout7, 'numeric');
            app.MissCountEditField_2Label = uilabel(app.GridLayout7);
            app.MissCountEditField_2Label.Text = 'Miss Count: ';
            app.MissCountEditField_2 = uieditfield(app.GridLayout7, 'numeric');
            app.EfficiencyStateLabel = uilabel(app.GridLayout7);
            app.EfficiencyStateLabel.Text = 'Efficiency State (%): ';
            app.EfficiencyStateEditField = uieditfield(app.GridLayout7, 'numeric');
            app.EfficiencyStateEditField.Editable = 'off';
            app.TestingPanel = uipanel(app.GridLayout5);
            app.TestingPanel.Title = 'Testing';
            app.GridLayout8 = uigridlayout(app.TestingPanel);
            app.StartTestButton = uibutton(app.GridLayout8, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.HitCountEditFieldLabel = uilabel(app.GridLayout8);
            app.HitCountEditFieldLabel.Text = 'Hit Count: ';
            app.HitCountEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MissCountEditFieldLabel = uilabel(app.GridLayout8);
            app.MissCountEditFieldLabel.Text = 'Miss Count: ';
            app.MissCountEditField = uieditfield(app.GridLayout8, 'numeric');
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.AllSetCheckBox = uicheckbox(app.GridLayout9);
            app.AllSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllSetCheckBoxValueChanged, true);
            app.AllSetCheckBox.Text = '  All Set';
            app.Panel_4 = uipanel(app.GridLayout2);
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, 'All Three Stages Graph')
            xlabel(app.UIAxes, 'Epoch')
            ylabel(app.UIAxes, 'Hits/Miss (%)')
            app.UIAxes.XLim = [0 50];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.XTick = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48];
            app.UIAxes.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.GridLayout10 = uigridlayout(app.GridLayout2);
            app.KeepTrackButton = uibutton(app.GridLayout10, 'push');
            app.KeepTrackButton.Text = 'Keep Track';
            app.SaveNetworkButton = uibutton(app.GridLayout10, 'push');
            app.SaveNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNetworkButtonPushed, true);
            app.SaveNetworkButton.Text = 'Save Network';
            % Show the figure after all components are created
            app.MLPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mlp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MLPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MLPUIFigure)
        end
    end
end